classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        BalanceLabel  matlab.ui.control.Label
        Balancetxt    matlab.ui.control.NumericEditField
        RMBLabel      matlab.ui.control.Label
        RMBtxt        matlab.ui.control.NumericEditField
        Withdraw      matlab.ui.control.Button
        Deposite      matlab.ui.control.Button
    end

    
    properties (Access = private)
        Balance double; % Description
        RMB double;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            app.Balance = 500000;
            app.Balancetxt.Value = app.Balance;
            app.RMB = 100;
            app.RMBtxt.Value = app.RMB;
        end

        % Button pushed function: Withdraw
        function WithdrawButtonPushed(app, event)
            value = app.RMBtxt.Value;
            if app.Balancetxt.Value >= value
                app.Balance = app.Balance - value;
                app.Balancetxt.Value = app.Balance;
            end
        end

        % Button pushed function: Deposite
        function DepositeButtonPushed(app, event)
            value = app.RMBtxt.Value;
            app.Balance = app.Balance + value;
            app.Balancetxt.Value = app.Balance;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BalanceLabel = uilabel(app.UIFigure);
            app.BalanceLabel.Text = 'Balance';
            app.Balancetxt = uieditfield(app.UIFigure, 'numeric');
            app.Balancetxt.ValueDisplayFormat = '%.0f';
            app.Balancetxt.Value = 500000;
            app.RMBLabel = uilabel(app.UIFigure);
            app.RMBLabel.Text = 'RMB';
            app.RMBtxt = uieditfield(app.UIFigure, 'numeric');
            app.RMBtxt.Limits = [100 30000];
            app.RMBtxt.ValueDisplayFormat = '%.0f';
            app.RMBtxt.Value = 100;
            app.Withdraw = uibutton(app.UIFigure, 'push');
            app.Withdraw.ButtonPushedFcn = createCallbackFcn(app, @WithdrawButtonPushed, true);
            app.Withdraw.Text = 'Withdraw';
            app.Deposite = uibutton(app.UIFigure, 'push');
            app.Deposite.ButtonPushedFcn = createCallbackFcn(app, @DepositeButtonPushed, true);
            app.Deposite.Text = 'Deposite';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end